classdef mygui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtractSiftFeatures        matlab.ui.Figure
        GoButton                   matlab.ui.control.Button
        FirstImageLabel            matlab.ui.control.Label
        FirstImageEditField        matlab.ui.control.EditField
        SecondImageEditFieldLabel  matlab.ui.control.Label
        SecondImageEditField       matlab.ui.control.EditField
        VisualizationButtonGroup   matlab.ui.container.ButtonGroup
        MontageButton              matlab.ui.control.RadioButton
        StackButton                matlab.ui.control.RadioButton
    end

    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
             imgpath1 = app.FirstImageEditField.Value;
             if isempty(imgpath1)
                f = errordlg('Enter a value','Input Error');
             else
                 imgpath2 = app.SecondImageEditField.Value;
                    if isempty(imgpath2)
                        f2 = errordlg('Enter a value','Input Error');
                    else
                        iscorrect = 1;
                        try
                        [a1,b1] = mySift(imgpath1);
                        [a2,b2] = mySift(imgpath2);
                        catch me
                            iscorrect = 0;
                            f3 = errordlg('File Not Found','File Error');
                        end
                        if app.MontageButton.Value == true && iscorrect == 1
                            matchSiftFeatures(imgpath1,imgpath2,b1,a1,b2,a2,1);
                        elseif app.StackButton.Value == true && iscorrect == 1
                            matchSiftFeatures(imgpath1,imgpath2,b1,a1,b2,a2,2);
                        end
                    end
             end
        end

        % Value changed function: FirstImageEditField
        function FirstImageEditFieldValueChanged(app, event)
            value = app.FirstImageEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtractSiftFeatures = uifigure;
            app.ExtractSiftFeatures.Name = 'Extract Sift Features';
            app.GoButton = uibutton(app.ExtractSiftFeatures, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.FirstImageLabel = uilabel(app.ExtractSiftFeatures);
            app.FirstImageLabel.Text = {'First Image:'; ''};
            app.FirstImageEditField = uieditfield(app.ExtractSiftFeatures, 'text');
            app.FirstImageEditField.ValueChangedFcn = createCallbackFcn(app, @FirstImageEditFieldValueChanged, true);
            app.SecondImageEditFieldLabel = uilabel(app.ExtractSiftFeatures);
            app.SecondImageEditFieldLabel.Text = 'Second Image:';
            app.SecondImageEditField = uieditfield(app.ExtractSiftFeatures, 'text');
            app.VisualizationButtonGroup = uibuttongroup(app.ExtractSiftFeatures);
            app.VisualizationButtonGroup.Title = 'Visualization';
            app.MontageButton = uiradiobutton(app.VisualizationButtonGroup);
            app.MontageButton.Text = 'Montage';
            app.MontageButton.Value = true;
            app.StackButton = uiradiobutton(app.VisualizationButtonGroup);
            app.StackButton.Text = 'Stack';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mygui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExtractSiftFeatures)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtractSiftFeatures)
        end
    end
end